classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RtolEditField                  matlab.ui.control.NumericEditField
        RtolEditFieldLabel             matlab.ui.control.Label
        AtolEditField                  matlab.ui.control.NumericEditField
        AtolEditFieldLabel             matlab.ui.control.Label
        methodsDropDown                matlab.ui.control.DropDown
        facSlider                      matlab.ui.control.Slider
        ODEDropDown                    matlab.ui.control.DropDown
        SelectthesafetyfactorfacLabel  matlab.ui.control.Label
        SettheabsoluteatolandrelativertoltolerancesLabel  matlab.ui.control.Label
        PrimarigaSecondarigaLabel      matlab.ui.control.Label
        SelecttheODEtoanalyzeLabel     matlab.ui.control.Label
        plotButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)
    
        function [fun, t_span, y0, sol_precisa] = getSelectedFunction(app)
            % Legge la funzione selezionata dal dropdown
            fun_selected = app.ODEDropDown.Value;
            index = find(strcmp(app.ODEDropDown.Items, fun_selected));  % indice numerico

            % Parametri
            T = [20,20,2];
            y0s = {[1.5;3], 1, 5};
            f1 = @(t,y) (3 + cos(t) - y);
            f2 = @(t,y) (y.*(2+y).*(2-y));
            functions = {@brusselator, f1, f2};
            syms t y(t)
            ode1= diff(y,t)==3+cos(t)-y; cond1= y(0)==1;
            ysolv1(t)=dsolve(ode1,cond1);
            
            ode2= diff(y,t)==y*(2+y)*(2-y); cond2= y(0)==5;
            ysolv2(t)=dsolve(ode2,cond2);


            soluzioni_precise={[],ysolv1,ysolv2};

    
            % Seleziona funzione e parametri
            fun = functions{index};
            t_span = [0, T(index)];
            sol_precisa = soluzioni_precise{index};
            y0 = y0s{index};

        end
    
        
        function [atol,rtol] = getTollerances(app)
            % Legge i valori inseriti nei Numeric Edit Field
            atol = app.AtolEditField.Value;
            rtol = app.RtolEditField.Value;
        end

        function [fac] = getFac(app)
            % Legge i valori inseriti nei Numeric Edit Field
            fac = app.facSlider.Value;
        end
        
        function tableau = getTableau(app)
            METODO = app.methodsDropDown.Value;
         
            % --- Butcher Tableaux Definitions ---
            
            methods = struct();
            % Method 1: ERK4(3) - 4th order method with 3rd order embedded error estimate
            methods(1).name = 'ERK4(3)';
            methods(1).A = [0, 0, 0, 0;
                         1/3, 0, 0, 0;
                         -1/3, 1, 0, 0;
                         1, -1, 1, 0];
            methods(1).c = [0, 1/3, 2/3, 1];
            methods(1).b = [1/8, 3/8, 3/8, 1/8];
            methods(1).b_hat = [1/12, 1/2, 1/4, 0, 1/6]; % Note: Last coefficient is for k_next (FSAL)
            methods(1).p = 4;
            methods(1).q = 3;
            
            % Method 2: Bogacki-Shampine 3(2)
            methods(2).name = 'BS3(2)';
            methods(2).A = [0, 0, 0, 0;
                         1/2, 0, 0, 0;
                         0, 3/4, 0, 0;
                         2/9, 1/3, 4/9, 0];
            methods(2).c = [0, 1/2, 3/4, 1];
            methods(2).b = [2/9, 1/3, 4/9, 0];
            methods(2).b_hat = [7/24, 1/4, 1/3, 1/8];
            methods(2).p = 3;
            methods(2).q = 2;
            
            % Method 3: Dormand-Prince 5(4) - This is the method used in MATLAB's ode45
            methods(3).name = 'DP5(4)';
            methods(3).c = [0, 1/5, 3/10, 4/5, 8/9, 1, 1];
            methods(3).A = [0, 0, 0, 0, 0, 0, 0;
                         1/5, 0, 0, 0, 0, 0, 0;
                         3/40, 9/40, 0, 0, 0, 0, 0;
                         44/45, -56/15, 32/9, 0, 0, 0, 0;
                         19372/6561, -25360/2187, 64448/6561, -212/729, 0, 0, 0;
                         9017/3168, -355/33, 46732/5247, 49/176, -5103/18656, 0, 0;
                         35/384, 0, 500/1113, 125/192, -2187/6784, 11/84, 0];
            methods(3).b = [35/384, 0, 500/1113, 125/192, -2187/6784, 11/84, 0];
            methods(3).b_hat = [5179/57600, 0, 7571/16695, 393/640, -92097/339200, 187/2100, 1/40];
            methods(3).p = 5;
            methods(3).q = 4;

            selected_method_index = find(strcmp({methods.name}, METODO));
            tableau = methods(selected_method_index); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)

            cla(app.UIAxes) 
            cla(app.UIAxes2) 
            cla(app.UIAxes3) 


            % prendi dati dati dall'utente
            p_embedded = 4; % Ordine del metodo embedded (4(3))
            [atol,rtol] = app.getTollerances();
            [fun, t_span, y0, sol_precisa] = app.getSelectedFunction();
            tableau = app.getTableau();
            x0 = t_span(1);
            if ~isempty(sol_precisa)
                sol=matlabFunction(sol_precisa);
            else
                options = odeset('RelTol', 1e-12, 'AbsTol', 1e-12);
                sol_struct = ode15s(fun, t_span, y0, options);
                sol = @(t) deval(sol_struct, t);
            end


            % Calcolo del passo iniziale suggerito
            h0 = initial_step_selector(fun, x0, y0, p_embedded, atol, rtol);

            t_current = x0;
            y_current = y0;
            h = h0; % Usa il passo iniziale calcolato
            k1 = []; % Per FSAL, inizialmente vuoto
            
            % Array per salvare i risultati
            t_embedded = t_current;
            y_embedded = y_current;
            
            % Parametri per il controllo del passo
            q = 3; % Ordine dell'errore per il metodo 4(3)
            fac = app.getFac();
            facmin = 0.1;
            facmax = 5.0;
            max_steps = 5000;
            steps_taken = 0;
            rejected_steps = 0;
            
            % Inizializza vettori di log prima del loop
            h_history = [];
            t_history = [];
            err_history = [];
            global_err_history = [];
            exact_local_err_history = [];
            is_accepted = []; % 1 per accettato, 0 per rifiutato
            
            while t_current < t_span(2) && steps_taken < max_steps
                steps_taken = steps_taken + 1;
                
                if t_current + h > t_span(2)
                    h_tentativo = t_span(2) - t_current; 
                else
                    h_tentativo = h;
                end
                [y_high, y_low, k_next, err] = embedded_solver(fun, t_current, y_current, h_tentativo, k1, atol, rtol, tableau);
               
                % --- LOG DI OGNI TENTATIVO ---
                t_history(end+1) = t_current;
                h_history(end+1) = h_tentativo;
            
                if err < 1
                    % PASSO ACCETTATO
                    is_accepted(end+1) = 1;
                    err_history(end+1) = norm(y_low-y_high);

                    t_prev = t_current;
                    t_current = t_current + h_tentativo;
                    y_current = y_high;
                    k1 = k_next;
                    
                    t_embedded(:, end+1) = t_current;
                    y_embedded(:, end+1) = y_current;
                    facmax_current = facmax;

                    if ~isempty(sol)
                        % Calcolo errore globale
                        global_err = norm(y_current - sol(t_current));
                        global_err_history(end+1) = global_err;
                        
                        % Calcolo errore locale esatto
                        y_true_start = sol(t_prev);
                        [y_num_onestep, ~, ~, ~] = embedded_solver(fun, t_prev, y_true_start, h, [], atol, rtol, tableau);
                        exact_local_err = norm(y_num_onestep - sol(t_current));
                        exact_local_err_history(end+1) = exact_local_err;
                    end
                else
                    % PASSO RIFIUTATO
                    is_accepted(end+1) = 0;
                    
                    rejected_steps = rejected_steps + 1;
                    facmax_current = 1;
                end
                
                h = step_control(h_tentativo, err, q, fac, facmin, facmax_current);
            end
                
            % Separiamo i dati per il plot
            t_acc = t_history(is_accepted == 1);
            h_acc = h_history(is_accepted == 1);
            
            t_rej = t_history(is_accepted == 0);
            h_rej = h_history(is_accepted == 0);
            
            % Plot in scala semilogaritmica
            semilogy(app.UIAxes,t_acc, h_acc, 'b.-', 'LineWidth', 1, 'MarkerSize', 10); 
            hold(app.UIAxes,'on')
            semilogy(app.UIAxes,t_rej, h_rej, 'rx', 'LineWidth', 1.5, 'MarkerSize', 8);
            legend(app.UIAxes,'accepted steps', 'rejected steps', 'Location', 'best');
            grid(app.UIAxes,'on')                   % griglia nell'UIAxes
            
            % Plot degli errori se la soluzione analitica è disponibile
            if ~isempty(sol)
                semilogy(app.UIAxes3,t_embedded(2:end), err_history, 'b-', 'DisplayName', 'Stima Errore Locale');
                hold(app.UIAxes3,"on");
                semilogy(app.UIAxes3,t_embedded(2:end), global_err_history, 'r-', 'DisplayName', 'Errore Globale');
                hold(app.UIAxes3,"on");
                semilogy(app.UIAxes3,t_embedded(2:end), exact_local_err_history, 'g--', 'DisplayName', 'Errore Locale Esatto');
                xlabel(app.UIAxes3,'t');
                ylabel(app.UIAxes3,'Error');
                legend(app.UIAxes3,'show', 'Location', 'best');
            end


            % Plot soluzione
            plot(app.UIAxes2,t_embedded, y_embedded(1,:), 'g-', 'LineWidth', 1.5, 'DisplayName', 'y1');
            hold(app.UIAxes2,'on')
            if length(y0)==2
                plot(app.UIAxes2,t_embedded, y_embedded(2,:), 'c-', 'LineWidth', 1.5, 'DisplayName', 'y2');
            end
            hold(app.UIAxes2,'on')
            if isscalar(y0)
                fplot(app.UIAxes2,sol_precisa,t_span, 'r--', 'LineWidth', 1.5, 'DisplayName', 'exact solution')
            else
                [t, y] = ode45(fun, t_span, y0);
                plot(app.UIAxes2,t, y(:,1), 'b--', 'LineWidth', 1.5, 'DisplayName', 'exact solution y1');
                hold(app.UIAxes2,"on")
                plot(app.UIAxes2,t, y(:,2), 'r--', 'LineWidth', 1.5, 'DisplayName', 'exact solution y2');
            end
            xlabel(app.UIAxes2,'t');
            ylabel(app.UIAxes2,'y');
            legend(app.UIAxes2,'show', 'Location', 'best');


        end

        % Value changed function: AtolEditField
        function AtolEditFieldValueChanged(app, event)

        end

        % Value changed function: RtolEditField
        function RtolEditFieldValueChanged(app, event)
     
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {618, 618};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.plotButton = uibutton(app.LeftPanel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.SelecttheODEtoanalyzeLabel = uilabel(app.LeftPanel);
            app.SelecttheODEtoanalyzeLabel.Interpreter = 'latex';
            app.SelecttheODEtoanalyzeLabel.Text = 'Select the ODE to analyze:';
            app.PrimarigaSecondarigaLabel = uilabel(app.LeftPanel);
            app.PrimarigaSecondarigaLabel.Interpreter = 'latex';
            app.PrimarigaSecondarigaLabel.Text = {'{Select the Runge–Kutta method'; ' for adaptive step-size integration:'; ' }'};
            app.SettheabsoluteatolandrelativertoltolerancesLabel = uilabel(app.LeftPanel);
            app.SettheabsoluteatolandrelativertoltolerancesLabel.Interpreter = 'latex';
            app.SettheabsoluteatolandrelativertoltolerancesLabel.Text = {'{Set the absolute (atol) and'; ' relative (rtol) tolerances:}'};
            app.SelectthesafetyfactorfacLabel = uilabel(app.LeftPanel);
            app.SelectthesafetyfactorfacLabel.Interpreter = 'latex';
            app.SelectthesafetyfactorfacLabel.Text = 'Select the safety factor (fac):';
            app.ODEDropDown = uidropdown(app.LeftPanel);
            app.ODEDropDown.Items = {'Brusselator', 'y''=3+cos(t)-y', 'y''=y(2+y)(2-y)'};
            app.ODEDropDown.Value = 'Brusselator';
            app.facSlider = uislider(app.LeftPanel);
            app.facSlider.Limits = [0.1 1];
            app.facSlider.MajorTicks = [0.1 0.45 0.8 1];
            app.facSlider.MajorTickLabels = {'0.1', '0.45', '0.8', '1'};
            app.facSlider.Value = 0.8;
            app.methodsDropDown = uidropdown(app.LeftPanel);
            app.methodsDropDown.Items = {'ERK4(3)', 'BS3(2)', 'DP5(4)'};
            app.methodsDropDown.Value = 'ERK4(3)';
            app.AtolEditFieldLabel = uilabel(app.LeftPanel);
            app.AtolEditFieldLabel.Text = 'Atol';
            app.AtolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AtolEditField.Limits = [1e-08 0.1];
            app.AtolEditField.ValueChangedFcn = createCallbackFcn(app, @AtolEditFieldValueChanged, true);
            app.AtolEditField.Value = 0.0001;
            app.RtolEditFieldLabel = uilabel(app.LeftPanel);
            app.RtolEditFieldLabel.Text = 'Rtol';
            app.RtolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RtolEditField.Limits = [1e-08 0.1];
            app.RtolEditField.ValueChangedFcn = createCallbackFcn(app, @RtolEditFieldValueChanged, true);
            app.RtolEditField.Value = 0.0001;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Step Analysis')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'h')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'solution')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Error comparison')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end